classdef bias < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_6                         matlab.ui.control.Label
        GrowthRateEditField_4           matlab.ui.control.NumericEditField
        GrowthRateEditField_4Label      matlab.ui.control.Label
        GrowthRateEditField_3           matlab.ui.control.NumericEditField
        GrowthRateEditField_3Label      matlab.ui.control.Label
        GrowthRateEditField_2           matlab.ui.control.NumericEditField
        GrowthRateEditField_2Label      matlab.ui.control.Label
        GrowthRateEditField             matlab.ui.control.NumericEditField
        GrowthRateEditFieldLabel        matlab.ui.control.Label
        BiasFactorEditField_4           matlab.ui.control.NumericEditField
        BiasFactorEditField_4Label      matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        FirstLayerThicknessEditField_4  matlab.ui.control.NumericEditField
        FirstLayerThicknessEditField_4Label  matlab.ui.control.Label
        HesaplaButton_4                 matlab.ui.control.Button
        TotalLengthEditField_4          matlab.ui.control.NumericEditField
        TotalLengthEditField_4Label     matlab.ui.control.Label
        NumberofDivisionEditField_4     matlab.ui.control.NumericEditField
        NumberofDivisionEditField_4Label  matlab.ui.control.Label
        BiasFactorEditField_3           matlab.ui.control.NumericEditField
        BiasFactorEditField_3Label      matlab.ui.control.Label
        HesaplaButton_3                 matlab.ui.control.Button
        NumberofDivisionEditField_3     matlab.ui.control.NumericEditField
        NumberofDivisionEditField_3Label  matlab.ui.control.Label
        TotalLengthEditField_3          matlab.ui.control.NumericEditField
        TotalLengthEditField_3Label     matlab.ui.control.Label
        FirstLayerThicknessEditField_3  matlab.ui.control.NumericEditField
        FirstLayerThicknessEditField_3Label  matlab.ui.control.Label
        NumberofDivisionEditField_2     matlab.ui.control.NumericEditField
        NumberofDivisionEditField_2Label  matlab.ui.control.Label
        HesaplaButton_2                 matlab.ui.control.Button
        TotalLengthEditField_2          matlab.ui.control.NumericEditField
        TotalLengthEditField_2Label     matlab.ui.control.Label
        BiasFactorEditField_2           matlab.ui.control.NumericEditField
        BiasFactorEditField_2Label      matlab.ui.control.Label
        FirstLayerThicknessEditField_2  matlab.ui.control.NumericEditField
        FirstLayerThicknessEditField_2Label  matlab.ui.control.Label
        TotalLengthEditField            matlab.ui.control.NumericEditField
        TotalLengthEditFieldLabel       matlab.ui.control.Label
        HesaplaButton                   matlab.ui.control.Button
        NumberofDivisionEditField       matlab.ui.control.NumericEditField
        NumberofDivisionEditFieldLabel  matlab.ui.control.Label
        BiasFactorEditField             matlab.ui.control.NumericEditField
        BiasFactorEditFieldLabel        matlab.ui.control.Label
        FirstLayerThicknessEditField    matlab.ui.control.NumericEditField
        FirstLayerThicknessEditFieldLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HesaplaButton
        function HesaplaButtonPushed(app, event)
            syms L b n
            F_1(L,b,n) = L*(b^((1/(n-1)))-1)/(b^((n/(n-1)))-1);
            L_1 = app.FirstLayerThicknessEditField.Value;
            Bias = app.BiasFactorEditField.Value;
            NoD = app.NumberofDivisionEditField.Value;
            Length = double(vpasolve(F_1(L,Bias,NoD) == L_1,L));
            app.TotalLengthEditField.Value = Length;
            app.GrowthRateEditField.Value = double(Bias^(1/(NoD-1)));
        end

        % Button pushed function: HesaplaButton_2
        function HesaplaButton_2Pushed(app, event)
            syms L b n
            F_1(L,b,n) = L*(b^((1/(n-1)))-1)/(b^((n/(n-1)))-1);
            L_1 = app.FirstLayerThicknessEditField_2.Value;
            Bias = app.BiasFactorEditField_2.Value;
            T_Length = app.TotalLengthEditField_2.Value;
            N = double(vpasolve(F_1(T_Length,Bias,n) == L_1,n));
            app.NumberofDivisionEditField_2.Value = double(N);
                        app.GrowthRateEditField_2.Value = double(Bias^(1/(N-1)));

        end

        % Button pushed function: HesaplaButton_3
        function HesaplaButton_3Pushed(app, event)
            syms L b n
            F_1(L,b,n) = L*(b^((1/(n-1)))-1)/(b^((n/(n-1)))-1);
            L_1 = app.FirstLayerThicknessEditField_3.Value;
            T_Length = app.TotalLengthEditField_3.Value;
            NoD = app.NumberofDivisionEditField_3.Value;
            B = double(vpasolve(F_1(T_Length,b,NoD) == L_1,b));
            app.BiasFactorEditField_3.Value = B;
                        app.GrowthRateEditField_3.Value = double(B^(1/(NoD-1)));

        end

        % Button pushed function: HesaplaButton_4
        function HesaplaButton_4Pushed(app, event)
           syms L b n
            T_Length = app.TotalLengthEditField_4.Value;
            NoD = app.NumberofDivisionEditField_4.Value;
            Bias = app.BiasFactorEditField_4.Value;
            app.FirstLayerThicknessEditField_4.Value = FLT(T_Length,Bias,NoD);
            app.GrowthRateEditField_4.Value = double(Bias^(1/(NoD-1)));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FirstLayerThicknessEditFieldLabel = uilabel(app.UIFigure);
            app.FirstLayerThicknessEditFieldLabel.Text = 'First Layer Thickness';
            app.FirstLayerThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstLayerThicknessEditField.Limits = [0 Inf];
            app.FirstLayerThicknessEditField.ValueDisplayFormat = '%20.18f';
            app.BiasFactorEditFieldLabel = uilabel(app.UIFigure);
            app.BiasFactorEditFieldLabel.Text = 'Bias Factor';
            app.BiasFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.BiasFactorEditField.Limits = [1 Inf];
            app.BiasFactorEditField.ValueDisplayFormat = '%20.18f';
            app.BiasFactorEditField.Value = 1;
            app.NumberofDivisionEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDivisionEditFieldLabel.Text = 'Number of Division';
            app.NumberofDivisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDivisionEditField.Limits = [2 Inf];
            app.NumberofDivisionEditField.RoundFractionalValues = 'on';
            app.NumberofDivisionEditField.ValueDisplayFormat = '%20d';
            app.NumberofDivisionEditField.Value = 2;
            app.HesaplaButton = uibutton(app.UIFigure, 'push');
            app.HesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButtonPushed, true);
            app.HesaplaButton.Text = {'Hesapla'; ''};
            app.TotalLengthEditFieldLabel = uilabel(app.UIFigure);
            app.TotalLengthEditFieldLabel.Text = 'Total Length';
            app.TotalLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalLengthEditField.ValueDisplayFormat = '%20.18f';
            app.FirstLayerThicknessEditField_2Label = uilabel(app.UIFigure);
            app.FirstLayerThicknessEditField_2Label.Text = 'First Layer Thickness';
            app.FirstLayerThicknessEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FirstLayerThicknessEditField_2.Limits = [0 Inf];
            app.FirstLayerThicknessEditField_2.ValueDisplayFormat = '%20.18f';
            app.BiasFactorEditField_2Label = uilabel(app.UIFigure);
            app.BiasFactorEditField_2Label.Text = 'Bias Factor';
            app.BiasFactorEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BiasFactorEditField_2.Limits = [1 Inf];
            app.BiasFactorEditField_2.ValueDisplayFormat = '%20.18f';
            app.BiasFactorEditField_2.Value = 1;
            app.TotalLengthEditField_2Label = uilabel(app.UIFigure);
            app.TotalLengthEditField_2Label.Text = 'Total Length';
            app.TotalLengthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TotalLengthEditField_2.Limits = [0 Inf];
            app.TotalLengthEditField_2.ValueDisplayFormat = '%20.18f';
            app.HesaplaButton_2 = uibutton(app.UIFigure, 'push');
            app.HesaplaButton_2.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButton_2Pushed, true);
            app.HesaplaButton_2.Text = {'Hesapla'; ''};
            app.NumberofDivisionEditField_2Label = uilabel(app.UIFigure);
            app.NumberofDivisionEditField_2Label.Text = 'Number of Division';
            app.NumberofDivisionEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDivisionEditField_2.Limits = [2 Inf];
            app.NumberofDivisionEditField_2.RoundFractionalValues = 'on';
            app.NumberofDivisionEditField_2.ValueDisplayFormat = '%20d';
            app.NumberofDivisionEditField_2.Value = 2;
            app.FirstLayerThicknessEditField_3Label = uilabel(app.UIFigure);
            app.FirstLayerThicknessEditField_3Label.Text = 'First Layer Thickness';
            app.FirstLayerThicknessEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.FirstLayerThicknessEditField_3.Limits = [0 Inf];
            app.FirstLayerThicknessEditField_3.ValueDisplayFormat = '%20.18f';
            app.TotalLengthEditField_3Label = uilabel(app.UIFigure);
            app.TotalLengthEditField_3Label.Text = 'Total Length';
            app.TotalLengthEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.TotalLengthEditField_3.Limits = [0 Inf];
            app.TotalLengthEditField_3.ValueDisplayFormat = '%20.18f';
            app.NumberofDivisionEditField_3Label = uilabel(app.UIFigure);
            app.NumberofDivisionEditField_3Label.Text = 'Number of Division';
            app.NumberofDivisionEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDivisionEditField_3.Limits = [2 Inf];
            app.NumberofDivisionEditField_3.RoundFractionalValues = 'on';
            app.NumberofDivisionEditField_3.ValueDisplayFormat = '%20d';
            app.NumberofDivisionEditField_3.Value = 2;
            app.HesaplaButton_3 = uibutton(app.UIFigure, 'push');
            app.HesaplaButton_3.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButton_3Pushed, true);
            app.HesaplaButton_3.Text = {'Hesapla'; ''};
            app.BiasFactorEditField_3Label = uilabel(app.UIFigure);
            app.BiasFactorEditField_3Label.Text = 'Bias Factor';
            app.BiasFactorEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.BiasFactorEditField_3.Limits = [1 Inf];
            app.BiasFactorEditField_3.ValueDisplayFormat = '%20.18f';
            app.BiasFactorEditField_3.Value = 1;
            app.NumberofDivisionEditField_4Label = uilabel(app.UIFigure);
            app.NumberofDivisionEditField_4Label.Text = 'Number of Division';
            app.NumberofDivisionEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDivisionEditField_4.Limits = [2 Inf];
            app.NumberofDivisionEditField_4.RoundFractionalValues = 'on';
            app.NumberofDivisionEditField_4.ValueDisplayFormat = '%20d';
            app.NumberofDivisionEditField_4.Value = 2;
            app.TotalLengthEditField_4Label = uilabel(app.UIFigure);
            app.TotalLengthEditField_4Label.Text = 'Total Length';
            app.TotalLengthEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.TotalLengthEditField_4.Limits = [0 Inf];
            app.TotalLengthEditField_4.ValueDisplayFormat = '%20.18f';
            app.HesaplaButton_4 = uibutton(app.UIFigure, 'push');
            app.HesaplaButton_4.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButton_4Pushed, true);
            app.HesaplaButton_4.Text = {'Hesapla'; ''};
            app.FirstLayerThicknessEditField_4Label = uilabel(app.UIFigure);
            app.FirstLayerThicknessEditField_4Label.Text = 'First Layer Thickness';
            app.FirstLayerThicknessEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.FirstLayerThicknessEditField_4.ValueDisplayFormat = '%20.18f';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '----------------------------------------------------------------';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '----------------------------------------------------------------';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '----------------------------------------------------------------';
            app.BiasFactorEditField_4Label = uilabel(app.UIFigure);
            app.BiasFactorEditField_4Label.Text = 'Bias Factor';
            app.BiasFactorEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.BiasFactorEditField_4.Limits = [1 Inf];
            app.BiasFactorEditField_4.ValueDisplayFormat = '%20.18f';
            app.BiasFactorEditField_4.Value = 1;
            app.GrowthRateEditFieldLabel = uilabel(app.UIFigure);
            app.GrowthRateEditFieldLabel.Text = 'Growth Rate';
            app.GrowthRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.GrowthRateEditField.ValueDisplayFormat = '%20.18f';
            app.GrowthRateEditField_2Label = uilabel(app.UIFigure);
            app.GrowthRateEditField_2Label.Text = 'Growth Rate';
            app.GrowthRateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.GrowthRateEditField_2.ValueDisplayFormat = '%20.18f';
            app.GrowthRateEditField_3Label = uilabel(app.UIFigure);
            app.GrowthRateEditField_3Label.Text = 'Growth Rate';
            app.GrowthRateEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.GrowthRateEditField_3.ValueDisplayFormat = '%20.18f';
            app.GrowthRateEditField_4Label = uilabel(app.UIFigure);
            app.GrowthRateEditField_4Label.Text = 'Growth Rate';
            app.GrowthRateEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.GrowthRateEditField_4.ValueDisplayFormat = '%20.18f';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '----------------------------------------------------------------';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bias

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end